classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        NghiemTab                   matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        Result                      matlab.ui.control.Button
        Chiadoi                     matlab.ui.control.Button
        Lap                         matlab.ui.control.Button
        Newton                      matlab.ui.control.Button
        NhpphngtrnhLabel            matlab.ui.control.Label
        NhapPT                      matlab.ui.control.EditField
        EditFieldLabel              matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        NhapKhoangNghiem            matlab.ui.control.EditField
        TEditFieldLabel             matlab.ui.control.Label
        a                           matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        b                           matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel    matlab.ui.control.Label
        saiso                       matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel         matlab.ui.control.Label
        Solanlap                    matlab.ui.control.NumericEditField
        KtquEditFieldLabel          matlab.ui.control.Label
        Ketqua                      matlab.ui.control.NumericEditField
        NoisuyTab                   matlab.ui.container.Tab
        UIAxes_2                    matlab.ui.control.UIAxes
        NhpdliuxLabel_4             matlab.ui.control.Label
        Nhapdulieux                 matlab.ui.control.NumericEditField
        NhpdliuyLabel_4             matlab.ui.control.Label
        Nhapdulieuy                 matlab.ui.control.NumericEditField
        ChonphuongphapnoisuyButton  matlab.ui.container.ButtonGroup
        NewtonButton                matlab.ui.control.ToggleButton
        LagrangeButton              matlab.ui.control.ToggleButton
        KtquathcnisuyLabel          matlab.ui.control.Label
        Ketquadathucnoisu           matlab.ui.control.EditField
        NhpgitrcnnisuyLabel         matlab.ui.control.Label
        Nhapgiatricannoisuy         matlab.ui.control.NumericEditField
        KtquButton                  matlab.ui.control.Button
        HoiquyTab                   matlab.ui.container.Tab
        NhpdliuxEditFieldLabel      matlab.ui.control.Label
        ChnphngphphiquyButtonGroup  matlab.ui.container.ButtonGroup
        TuyntnhButton               matlab.ui.control.ToggleButton
        HmmButton                   matlab.ui.control.ToggleButton
        LogaritButton               matlab.ui.control.ToggleButton
        KtqudonLabel                matlab.ui.control.Label
        Ketquadathucnoisu_3         matlab.ui.control.EditField
        UIAxes2                     matlab.ui.control.UIAxes
        KtquphngtrnhhiquyLabel      matlab.ui.control.Label
        Ketquadathucnoisu_2         matlab.ui.control.EditField
        NhpdliuxEditField           matlab.ui.control.NumericEditField
        NhpgitrcndonLabel           matlab.ui.control.Label
        Ydata_DaoHam_3              matlab.ui.control.NumericEditField
        NhpdliuyEditFieldLabel      matlab.ui.control.Label
        NhpdliuyEditField           matlab.ui.control.NumericEditField
        DaohamTab                   matlab.ui.container.Tab
        bonustext_2                 matlab.ui.control.Label
        PhuongPhapButtonGroup_2     matlab.ui.container.ButtonGroup
        XpxtinliButton              matlab.ui.control.ToggleButton
        TrungtmButton               matlab.ui.control.ToggleButton
        NhpdliuxLabel_2             matlab.ui.control.Label
        Xdata_DaoHam                matlab.ui.control.NumericEditField
        NhpdliuyLabel_2             matlab.ui.control.Label
        Ydata_DaoHam                matlab.ui.control.NumericEditField
        NhphmsEditFieldLabel_2      matlab.ui.control.Label
        Hamso_DaoHam                matlab.ui.control.EditField
        NhpcntnhTchphnLabel_2       matlab.ui.control.Label
        GiatriDaoHam                matlab.ui.control.NumericEditField
        NhpbchLabel                 matlab.ui.control.Label
        BuochDaoHam                 matlab.ui.control.EditField
        ChngitrsaisOhhocOh2Label    matlab.ui.control.Label
        SaiSoDaoHam                 matlab.ui.control.EditField
        KtquEditFieldLabel_3        matlab.ui.control.Label
        KetQua_DaoHam               matlab.ui.control.NumericEditField
        TichPhanTab                 matlab.ui.container.Tab
        NhpdliuxLabel               matlab.ui.control.Label
        Xdata                       matlab.ui.control.NumericEditField
        NhpdliuyLabel               matlab.ui.control.Label
        Ydata                       matlab.ui.control.NumericEditField
        bonustext                   matlab.ui.control.Label
        NhphmsEditFieldLabel        matlab.ui.control.Label
        Hamso                       matlab.ui.control.EditField
        NhpNLabel                   matlab.ui.control.Label
        dataN                       matlab.ui.control.NumericEditField
        KtquEditFieldLabel_2        matlab.ui.control.Label
        KetQua                      matlab.ui.control.NumericEditField
        chonPhuongPhap              matlab.ui.control.Label
        HinhThangButton             matlab.ui.control.Button
        Simson38Button              matlab.ui.control.Button
        Simpson13Button             matlab.ui.control.Button
        CntrnbEditFieldLabel        matlab.ui.control.Label
        Canb                        matlab.ui.control.NumericEditField
        CndiaEditFieldLabel         matlab.ui.control.Label
        CanDuoia                    matlab.ui.control.NumericEditField
        IntroTab                    matlab.ui.container.Tab
        Tree                        matlab.ui.container.Tree
        THANHVIEN                   matlab.ui.container.TreeNode
        DinhDungNode                matlab.ui.container.TreeNode
        VanHyNode                   matlab.ui.container.TreeNode
        QuangDungNode               matlab.ui.container.TreeNode
        NguynHuyDuNode              matlab.ui.container.TreeNode
        BaoQuocNode                 matlab.ui.container.TreeNode
        MinhQuangNode               matlab.ui.container.TreeNode
        GIITHIUNHMLabel             matlab.ui.control.Label
        MSSVLabel                   matlab.ui.control.Label
        MSSV                        matlab.ui.control.EditField
        NHIMVEditFieldLabel         matlab.ui.control.Label
        NHIEMVU                     matlab.ui.control.EditField
        NGYSINHEditFieldLabel       matlab.ui.control.Label
        NGAYSINH                    matlab.ui.control.EditField
        CHCVEditFieldLabel          matlab.ui.control.Label
        CHUCVU                      matlab.ui.control.EditField
        LPEditFieldLabel            matlab.ui.control.Label
        LOP                         matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: Chiadoi
        function ChiadoiButtonPushed(app, event)
                syms x;
                f = str2func(['@(x)',app.NhapPT.Value]);
                app.Solanlap.Value = 0;    %khai báo biến đếm số lần lặp
                while(1)
                    app.Solanlap.Value = app.Solanlap.Value + 1;    %mỗi lần lặp lại biến solanlap +1
                    c = (app.a.Value + app.b.Value) / 2;              %tính c bằng (a+b)/2
                    if(f(c) * f(app.a.Value) < 0)         %nếu f(c)*f(a)<0 có nghĩa khoảng (a,c) có nghiệm
                        app.b.Value = c;                %thay b=c
                    else
                        app.a.Value = c;                %ngược lại nếu f(c)*f(a)>0 tức trong khoảng (a,c) không có nghiệm, nghiệm nằm trong khoảng (b,c) 
                    end
                    e = abs(app.b.Value - app.a.Value);             %tính trị tuyệt đối b-1
                    if(e <= app.saiso.Value)             %nếu e< sai số thì thoát lặp
                        break
                    end
                end
                app.Ketqua.Value = app.a.Value;               %nghiệm của phương trình bắng a

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
           
            if(selectedNodes==app.MinhQuangNode)
            app.MSSV.Value = '21200339';
            app.NGAYSINH.Value = '2/1/2003';
            app.NHIEMVU.Value = 'Thiết kế giao diện Tab Giới thiệu nhóm';
            app.LOP.Value = '21DTV2';
            app.CHUCVU.Value = 'Thành Viên';
            end

            if (selectedNodes==app.BaoQuocNode)
            app.MSSV.Value = '21200344';
            app.NGAYSINH.Value = '10/10/2003';
            app.NHIEMVU.Value = 'Thiết kế giao diện Tab Tích phân';
            app.LOP.Value = '21DTV2';
            app.CHUCVU.Value = 'Thành Viên';
            end

            if (selectedNodes==app.NguynHuyDuNode)
            app.MSSV.Value = '21200279';
            app.NGAYSINH.Value = '20/8/2003';
            app.NHIEMVU.Value = 'Thiết kế giao diện Tab Đạo hàm';
            app.LOP.Value = '21DTV2';
            app.CHUCVU.Value = 'Thành Viên';
            end
            if (selectedNodes==app.QuangDungNode)
            app.MSSV.Value = '21200282';
            app.NGAYSINH.Value = '18/12/2003';
            app.NHIEMVU.Value = 'Thiết kế giao diện Tab Hồi quy';
            app.LOP.Value = '21DTV2';
            app.CHUCVU.Value = 'Trưởng Nhóm';
            end
            if (selectedNodes==app.VanHyNode)
            app.MSSV.Value = '21200296';
            app.NGAYSINH.Value = '3/11/2003';
            app.NHIEMVU.Value = 'Thiết kế giao diện Tab Nội suy';
            app.LOP.Value = '21DTV2';
            app.CHUCVU.Value = 'Thành Viên';
            end
            if (selectedNodes==app.DinhDungNode)
            app.MSSV.Value = '21200280';
            app.NGAYSINH.Value = '16/6/2003';
            app.NHIEMVU.Value = 'Thiết kế giao diện Tab Tìm nghiệm';
            app.LOP.Value = '21DTV2';
            app.CHUCVU.Value = 'Thành Viên';
            end 
        end

        % Value changed function: MSSV
        function MSSVValueChanged(app, event)
            value = app.MSSV.Value;
            
        end

        % Value changed function: NGAYSINH
        function NGAYSINHValueChanged(app, event)
            value = app.NGAYSINH.Value;
            
        end

        % Value changed function: LOP
        function LOPValueChanged(app, event)
            value = app.LOP.Value;
            
        end

        % Value changed function: CHUCVU
        function CHUCVUValueChanged(app, event)
            value = app.CHUCVU.Value;
            
        end

        % Value changed function: NHIEMVU
        function NHIEMVUValueChanged(app, event)
            value = app.NHIEMVU.Value;
            
        end

        % Button pushed function: HinhThangButton
        function HinhThangButtonPushed(app, event)
             syms x;
             fx = str2func(['@(x)', app.Hamso.Value]);
             h = (app.Canb.Value - app.CanDuoia.Value)/app.dataN.Value;
             s1 = (h/2)*(fx(app.CanDuoia.Value) + fx(app.Canb.Value));
             s2 = 0;
             for i = 1:(app.dataN.Value)-1
                    s2 = s2 + fx(app.CanDuoia.Value + i*h);
             end
             app.KetQua.Value = s1 + h*s2;
        end

        % Button pushed function: Simpson13Button
        function Simpson13ButtonPushed(app, event)
            syms x;
            fx = str2func(['@(x)', app.Hamso.Value]);
            h = (app.Canb.Value - app.CanDuoia.Value)/app.dataN.Value;
            s1 = (fx(app.CanDuoia.Value) + fx(app.Canb.Value));
            s2 = 0; s3 = 0;
            for i=1:2:(app.dataN.Value)-1
                s2 = s2 + fx(app.CanDuoia.Value + i*h);
            end
    
            for i=2:2:(app.dataN.Value)-1
                s3 = s3 + fx(app.CanDuoia.Value + i*h);
            end
            app.KetQua.Value = h/3*(s1 + 4*s2 + 2*s3);
        end

        % Button pushed function: Lap
        function LapButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Result = uibutton(app.NghiemTab, 'push');
            app.Result.Text = 'Kết quả';
            app.Chiadoi = uibutton(app.NghiemTab, 'push');
            app.Chiadoi.ButtonPushedFcn = createCallbackFcn(app, @ChiadoiButtonPushed, true);
            app.Chiadoi.Text = 'Chia đôi';
            app.Lap = uibutton(app.NghiemTab, 'push');
            app.Lap.ButtonPushedFcn = createCallbackFcn(app, @LapButtonPushed, true);
            app.Lap.Text = 'Lặp';
            app.Newton = uibutton(app.NghiemTab, 'push');
            app.Newton.Text = 'Newton(Tiếp tuyến)';
            app.NhpphngtrnhLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình';
            app.NhapPT = uieditfield(app.NghiemTab, 'text');
            app.NhapPT.Value = 'x-sin(x)-0.25';
            app.EditFieldLabel = uilabel(app.NghiemTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.NghiemTab, 'text');
            app.EditField.Value = 'Chọn phương pháp tìm nghiệm ';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm';
            app.NhapKhoangNghiem = uieditfield(app.NghiemTab, 'text');
            app.NhapKhoangNghiem.Value = 'Nhập khoảng phân ly nghiệm';
            app.TEditFieldLabel = uilabel(app.NghiemTab);
            app.TEditFieldLabel.Text = 'Từ';
            app.a = uieditfield(app.NghiemTab, 'numeric');
            app.a.Value = 1;
            app.nEditFieldLabel = uilabel(app.NghiemTab);
            app.nEditFieldLabel.Text = 'Đến';
            app.b = uieditfield(app.NghiemTab, 'numeric');
            app.b.Value = 2;
            app.SaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.saiso = uieditfield(app.NghiemTab, 'numeric');
            app.saiso.Value = 0.005;
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.Solanlap = uieditfield(app.NghiemTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.NghiemTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.Ketqua = uieditfield(app.NghiemTab, 'numeric');
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội Suy';
            app.UIAxes_2 = uiaxes(app.NoisuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số nội suy và dữ liệu')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.NhpdliuxLabel_4 = uilabel(app.NoisuyTab);
            app.NhpdliuxLabel_4.Text = 'Nhập dữ liệu x';
            app.Nhapdulieux = uieditfield(app.NoisuyTab, 'numeric');
            app.NhpdliuyLabel_4 = uilabel(app.NoisuyTab);
            app.NhpdliuyLabel_4.Text = 'Nhập dữ liệu y';
            app.Nhapdulieuy = uieditfield(app.NoisuyTab, 'numeric');
            app.ChonphuongphapnoisuyButton = uibuttongroup(app.NoisuyTab);
            app.ChonphuongphapnoisuyButton.Title = 'Chọn phương pháp nội suy';
            app.NewtonButton = uitogglebutton(app.ChonphuongphapnoisuyButton);
            app.NewtonButton.Text = 'Newton';
            app.NewtonButton.Value = true;
            app.LagrangeButton = uitogglebutton(app.ChonphuongphapnoisuyButton);
            app.LagrangeButton.Text = 'Lagrange';
            app.KtquathcnisuyLabel = uilabel(app.NoisuyTab);
            app.KtquathcnisuyLabel.Text = 'Kết quả đa thức nội suy';
            app.Ketquadathucnoisu = uieditfield(app.NoisuyTab, 'text');
            app.NhpgitrcnnisuyLabel = uilabel(app.NoisuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy';
            app.Nhapgiatricannoisuy = uieditfield(app.NoisuyTab, 'numeric');
            app.KtquButton = uibutton(app.NoisuyTab, 'push');
            app.KtquButton.Text = 'Kết quả';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi Quy';
            app.NhpgitrcndonLabel = uilabel(app.HoiquyTab);
            app.NhpgitrcndonLabel.Text = 'Nhập giá trị cần dự đoán';
            app.Ydata_DaoHam_3 = uieditfield(app.HoiquyTab, 'numeric');
            app.NhpdliuyEditFieldLabel = uilabel(app.HoiquyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.NhpdliuxEditFieldLabel = uilabel(app.HoiquyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.ChnphngphphiquyButtonGroup = uibuttongroup(app.HoiquyTab);
            app.ChnphngphphiquyButtonGroup.Title = 'Chọn phương pháp hồi quy';
            app.TuyntnhButton = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.TuyntnhButton.Text = 'Tuyến tính';
            app.TuyntnhButton.Value = true;
            app.HmmButton = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.HmmButton.Text = 'Hàm mũ';
            app.LogaritButton = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.LogaritButton.Text = 'Logarit';
            app.KtquphngtrnhhiquyLabel = uilabel(app.HoiquyTab);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy';
            app.Ketquadathucnoisu_2 = uieditfield(app.HoiquyTab, 'text');
            app.UIAxes2 = uiaxes(app.HoiquyTab);
            title(app.UIAxes2, ' Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.KtqudonLabel = uilabel(app.HoiquyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.Ketquadathucnoisu_3 = uieditfield(app.HoiquyTab, 'text');
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo Hàm';
            app.bonustext_2 = uilabel(app.DaohamTab);
            app.bonustext_2.Text = 'Hoặc';
            app.PhuongPhapButtonGroup_2 = uibuttongroup(app.DaohamTab);
            app.PhuongPhapButtonGroup_2.Title = 'Chọn phương pháp Đạo hàm ';
            app.XpxtinliButton = uitogglebutton(app.PhuongPhapButtonGroup_2);
            app.XpxtinliButton.Text = 'Xấp xỉ tiến lùi ';
            app.XpxtinliButton.Value = true;
            app.TrungtmButton = uitogglebutton(app.PhuongPhapButtonGroup_2);
            app.TrungtmButton.Text = 'Trung tâm ';
            app.NhpdliuxLabel_2 = uilabel(app.DaohamTab);
            app.NhpdliuxLabel_2.Text = 'Nhập dữ liệu x';
            app.Xdata_DaoHam = uieditfield(app.DaohamTab, 'numeric');
            app.NhpdliuyLabel_2 = uilabel(app.DaohamTab);
            app.NhpdliuyLabel_2.Text = 'Nhập dữ liệu y';
            app.Ydata_DaoHam = uieditfield(app.DaohamTab, 'numeric');
            app.NhphmsEditFieldLabel_2 = uilabel(app.DaohamTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Hamso_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.NhpcntnhTchphnLabel_2 = uilabel(app.DaohamTab);
            app.NhpcntnhTchphnLabel_2.Text = 'Nhập giá trị cần tính Đạo hàm ';
            app.GiatriDaoHam = uieditfield(app.DaohamTab, 'numeric');
            app.NhpbchLabel = uilabel(app.DaohamTab);
            app.NhpbchLabel.Text = 'Nhập bước h ';
            app.BuochDaoHam = uieditfield(app.DaohamTab, 'text');
            app.ChngitrsaisOhhocOh2Label = uilabel(app.DaohamTab);
            app.ChngitrsaisOhhocOh2Label.Text = {'Chọn giá trị sai số '; 'O(h) hoặc O(h^2)'};
            app.SaiSoDaoHam = uieditfield(app.DaohamTab, 'text');
            app.KtquEditFieldLabel_3 = uilabel(app.DaohamTab);
            app.KtquEditFieldLabel_3.Text = 'Kết quả';
            app.KetQua_DaoHam = uieditfield(app.DaohamTab, 'numeric');
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích Phân';
            app.NhpdliuxLabel = uilabel(app.TichPhanTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.Xdata = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpdliuyLabel = uilabel(app.TichPhanTab);
            app.NhpdliuyLabel.Text = 'Nhập dữ liệu y';
            app.Ydata = uieditfield(app.TichPhanTab, 'numeric');
            app.bonustext = uilabel(app.TichPhanTab);
            app.bonustext.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Hamso = uieditfield(app.TichPhanTab, 'text');
            app.NhpNLabel = uilabel(app.TichPhanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.dataN = uieditfield(app.TichPhanTab, 'numeric');
            app.KtquEditFieldLabel_2 = uilabel(app.TichPhanTab);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.KetQua = uieditfield(app.TichPhanTab, 'numeric');
            app.chonPhuongPhap = uilabel(app.TichPhanTab);
            app.chonPhuongPhap.Text = 'Chọn phương pháp tích phân';
            app.HinhThangButton = uibutton(app.TichPhanTab, 'push');
            app.HinhThangButton.ButtonPushedFcn = createCallbackFcn(app, @HinhThangButtonPushed, true);
            app.HinhThangButton.Text = 'Hình Thang';
            app.Simson38Button = uibutton(app.TichPhanTab, 'push');
            app.Simson38Button.Text = 'Simson 3/8';
            app.Simpson13Button = uibutton(app.TichPhanTab, 'push');
            app.Simpson13Button.ButtonPushedFcn = createCallbackFcn(app, @Simpson13ButtonPushed, true);
            app.Simpson13Button.Text = 'Simpson 1/3';
            app.CntrnbEditFieldLabel = uilabel(app.TichPhanTab);
            app.CntrnbEditFieldLabel.Text = 'Cận trên (b)';
            app.Canb = uieditfield(app.TichPhanTab, 'numeric');
            app.CndiaEditFieldLabel = uilabel(app.TichPhanTab);
            app.CndiaEditFieldLabel.Text = 'Cận dưới (a)';
            app.CanDuoia = uieditfield(app.TichPhanTab, 'numeric');
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Giới thiệu nhóm';
            app.Tree = uitree(app.IntroTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.THANHVIEN = uitreenode(app.Tree);
            app.THANHVIEN.Text = '   THÀNH VIÊN';
            app.DinhDungNode = uitreenode(app.THANHVIEN);
            app.DinhDungNode.Text = 'Lê Đình Dũng';
            app.VanHyNode = uitreenode(app.THANHVIEN);
            app.VanHyNode.Text = 'Đặng Văn Hy';
            app.QuangDungNode = uitreenode(app.THANHVIEN);
            app.QuangDungNode.Text = 'Phạm Quang Dũng';
            app.NguynHuyDuNode = uitreenode(app.THANHVIEN);
            app.NguynHuyDuNode.Text = 'Nguyễn Huy Du';
            app.BaoQuocNode = uitreenode(app.THANHVIEN);
            app.BaoQuocNode.Text = 'Phạm Bảo Quốc';
            app.MinhQuangNode = uitreenode(app.THANHVIEN);
            app.MinhQuangNode.Text = 'Đỗ Minh Quang';
            app.GIITHIUNHMLabel = uilabel(app.IntroTab);
            app.GIITHIUNHMLabel.Text = 'GIỚI THIỆU NHÓM';
            app.MSSVLabel = uilabel(app.IntroTab);
            app.MSSVLabel.Text = 'MSSV ';
            app.MSSV = uieditfield(app.IntroTab, 'text');
            app.MSSV.ValueChangedFcn = createCallbackFcn(app, @MSSVValueChanged, true);
            app.MSSV.Editable = 'off';
            app.NHIMVEditFieldLabel = uilabel(app.IntroTab);
            app.NHIMVEditFieldLabel.Text = 'NHIỆM VỤ';
            app.NHIEMVU = uieditfield(app.IntroTab, 'text');
            app.NHIEMVU.ValueChangedFcn = createCallbackFcn(app, @NHIEMVUValueChanged, true);
            app.NHIEMVU.Editable = 'off';
            app.NGYSINHEditFieldLabel = uilabel(app.IntroTab);
            app.NGYSINHEditFieldLabel.Text = 'NGÀY SINH';
            app.NGAYSINH = uieditfield(app.IntroTab, 'text');
            app.NGAYSINH.ValueChangedFcn = createCallbackFcn(app, @NGAYSINHValueChanged, true);
            app.NGAYSINH.Editable = 'off';
            app.CHCVEditFieldLabel = uilabel(app.IntroTab);
            app.CHCVEditFieldLabel.Text = 'CHỨC VỤ';
            app.CHUCVU = uieditfield(app.IntroTab, 'text');
            app.CHUCVU.ValueChangedFcn = createCallbackFcn(app, @CHUCVUValueChanged, true);
            app.CHUCVU.Editable = 'off';
            app.LPEditFieldLabel = uilabel(app.IntroTab);
            app.LPEditFieldLabel.Text = 'LỚP';
            app.LOP = uieditfield(app.IntroTab, 'text');
            app.LOP.ValueChangedFcn = createCallbackFcn(app, @LOPValueChanged, true);
            app.LOP.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end